classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Slider_5        matlab.ui.control.Slider
        Label_5         matlab.ui.control.Label
        Slider_4        matlab.ui.control.Slider
        Label_4         matlab.ui.control.Label
        kSlider_5       matlab.ui.control.Slider
        kSlider_5Label  matlab.ui.control.Label
        Button_3        matlab.ui.control.Button
        kSlider_4       matlab.ui.control.Slider
        kLabel          matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        kSlider_3       matlab.ui.control.Slider
        kSlider_3Label  matlab.ui.control.Label
        kSlider_2       matlab.ui.control.Slider
        kSlider_2Label  matlab.ui.control.Label
        kSlider         matlab.ui.control.Slider
        kSliderLabel    matlab.ui.control.Label
        Slider_3        matlab.ui.control.Slider
        Label_3         matlab.ui.control.Label
        Slider_2        matlab.ui.control.Slider
        Label_2         matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Slider          matlab.ui.control.Slider
        Button          matlab.ui.control.Button
        TextArea        matlab.ui.control.TextArea
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        audio;
        fs;
        comp_20=0.0;
        comp_50=0.0
        comp_100=0.0;
        comp_200=0.0;
        comp_500=0.0;
        comp_1k=0.0;
        comp_2k=0.0;
        comp_4k=0.0;
        comp_8k=0.0;
        comp_16k=0.0
        new_audio=0.0;
    end
    
    methods (Access = private)
        
        function [Y] = fx_FIR(~,fs,wc1,wc2,signal)
            Wn=[wc1*2 wc2*2]/fs;                              
            [b,a]=butter(1,Wn);     
            Y=filtfilt(b,a,signal);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.audio,app.fs]=audioread('chopin.mp3');
            app.audio=app.audio(:,1);
            f=abs(fftshift(fft(app.audio)));
            xlim(app.UIAxes,[2000000 3500000]);
            plot(app.UIAxes,f);
            app.TextArea.Value='chopin.mp3';
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.comp_100=changingValue;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_200=changingValue;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_500=changingValue;
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
            changingValue = event.Value;
            app.comp_1k=changingValue;
        end

        % Value changing function: kSlider_2
        function kSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_2k=changingValue;
        end

        % Value changing function: kSlider_3
        function kSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_4k=changingValue;
        end

        % Value changing function: kSlider_4
        function kSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_8k=changingValue;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            audio_20=fx_FIR(app,app.fs,1,20,app.audio);
            audio_50=fx_FIR(app,app.fs,20,50,app.audio);
            audio_100=fx_FIR(app,app.fs,50,100,app.audio);
            audio_200=fx_FIR(app,app.fs,100,200,app.audio);
            audio_500=fx_FIR(app,app.fs,200,500,app.audio);
            audio_1k=fx_FIR(app,app.fs,500,1000,app.audio);
            audio_2k=fx_FIR(app,app.fs,1000,2000,app.audio);
            audio_4k=fx_FIR(app,app.fs,2000,4000,app.audio);
            audio_8k=fx_FIR(app,app.fs,4000,8000,app.audio);
            audio_16k=fx_FIR(app,app.fs,8000,16000,app.audio);
            app.new_audio = audio_20.*app.comp_20+audio_50.*app.comp_50+audio_100.*app.comp_100+audio_200.*app.comp_200+audio_500.*app.comp_500+audio_1k.*app.comp_1k+audio_2k.*app.comp_2k+audio_4k.*app.comp_4k+audio_8k.*app.comp_8k+audio_16k.*app.comp_16k;
            app.new_audio=app.new_audio./100;
            audio_f=abs(fftshift(fft(app.new_audio)));
            xlim(app.UIAxes2,[2000000 3500000]);
            plot(app.UIAxes2,audio_f);
            sound(app.new_audio,app.fs);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            clear sound;
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_20=changingValue/100;
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_50=changingValue/100;
        end

        % Value changing function: kSlider_5
        function kSlider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.comp_16k=changingValue/100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '加载音频文件';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '100';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '200';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '500';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = '1k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.kSlider_2Label = uilabel(app.UIFigure);
            app.kSlider_2Label.Text = '2k';
            app.kSlider_2 = uislider(app.UIFigure);
            app.kSlider_2.ValueChangingFcn = createCallbackFcn(app, @kSlider_2ValueChanging, true);
            app.kSlider_3Label = uilabel(app.UIFigure);
            app.kSlider_3Label.Text = '4k';
            app.kSlider_3 = uislider(app.UIFigure);
            app.kSlider_3.ValueChangingFcn = createCallbackFcn(app, @kSlider_3ValueChanging, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '处理并播放';
            app.kLabel = uilabel(app.UIFigure);
            app.kLabel.Text = '8k';
            app.kSlider_4 = uislider(app.UIFigure);
            app.kSlider_4.ValueChangingFcn = createCallbackFcn(app, @kSlider_4ValueChanging, true);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '停止播放';
            app.kSlider_5Label = uilabel(app.UIFigure);
            app.kSlider_5Label.Text = '16k';
            app.kSlider_5 = uislider(app.UIFigure);
            app.kSlider_5.ValueChangingFcn = createCallbackFcn(app, @kSlider_5ValueChanging, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '50';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '20';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end